classdef ScientificCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        Button_TanGraph     matlab.ui.control.Button
        Button_CosGraph     matlab.ui.control.Button
        Button_SinGraph     matlab.ui.control.Button
        ResultDisplay       matlab.ui.control.EditField
        Button_BackSpace    matlab.ui.control.Button
        Button_Power        matlab.ui.control.Button
        piButton            matlab.ui.control.Button
        xButton_Inverse     matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Subtract            matlab.ui.control.Button
        Button_Add          matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button_1            matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        Button_Equals       matlab.ui.control.Button
        Button_Negate       matlab.ui.control.Button
        Button_Decimal      matlab.ui.control.Button
        Button_0            matlab.ui.control.Button
        Button_Multiply     matlab.ui.control.Button
        Button_9            matlab.ui.control.Button
        Button_8            matlab.ui.control.Button
        Button_7            matlab.ui.control.Button
        Button_ClosedParen  matlab.ui.control.Button
        Button_Divide       matlab.ui.control.Button
        Button_OpenParen    matlab.ui.control.Button
        Button_Sqrt         matlab.ui.control.Button
        Button_Square       matlab.ui.control.Button
        Button_Clear        matlab.ui.control.Button
        Button_Log          matlab.ui.control.Button
        Button_Tan          matlab.ui.control.Button
        Button_Cos          matlab.ui.control.Button
        Button_Sin          matlab.ui.control.Button
        GraphAxes           matlab.ui.control.UIAxes
    end

  
methods (Access = private)

    % 1. Number Buttons (0–9)
    function NumberButtonPushed(app, event)
        currentText = string(app.ResultDisplay.Value);
        clickedNum  = event.Source.Text;

        if currentText == "0"
            app.ResultDisplay.Value = clickedNum;
        else
            app.ResultDisplay.Value = currentText + clickedNum;
        end
    end

    % 2. Operation Buttons (+, -, *, /)
    function OperationButtonPushed(app, event)
    currentText = string(app.ResultDisplay.Value);
    symbol = event.Source.Text;

    if currentText == "0"
        % Replace 0 with the symbol
        app.ResultDisplay.Value = symbol;
    else
        % Append symbol
        app.ResultDisplay.Value = currentText + symbol;
    end
    end

    % 3. Equals Button (=)
    function EqualsButtonPushed(app, ~)
        try
            equation = char(app.ResultDisplay.Value); % eval needs char
            result = eval(equation);
            app.ResultDisplay.Value = string(result);
        catch
            app.ResultDisplay.Value = "Error";
        end
    end

    % 4. Clear Button (C)
    function ClearButtonPushed(app, ~)
        app.ResultDisplay.Value = "0";
    end

    % 5. Backspace Button (DEL)
    function BackspaceButtonPushed(app, ~)
      txt = string(app.ResultDisplay.Value);

    % If display is Error, reset to 0
      if txt == "Error"
        app.ResultDisplay.Value = "0";
        return;
      end

      if strlength(txt) > 1
        app.ResultDisplay.Value = extractBefore(txt, strlength(txt));
      else
        app.ResultDisplay.Value = "0";
      end
     end

    % 6. Scientific Buttons
    function ScienceButtonPushed(app, event)

    txt  = string(app.ResultDisplay.Value);
    func = event.Source.Text;

    % If display is 0, start with function
    if txt == "0"
        app.ResultDisplay.Value = func + "(";
        return;
    end

    % Check if multiplication is needed
    lastChar = extractAfter(txt, strlength(txt)-1);

    needsMultiply = isstrprop(lastChar, 'digit') || lastChar == ")";

    mul = "";
    if needsMultiply
        mul = "*";
    end

    switch func
        case 'x^2'
            app.ResultDisplay.Value = txt + "^2";

        case 'sqrt'
            app.ResultDisplay.Value = txt + mul + "sqrt(";

        case '1/x'
            app.ResultDisplay.Value = "1/(" + txt + ")";

        case '+/-'
            app.ResultDisplay.Value = "-(" + txt + ")";

        otherwise
            % sin, cos, tan, log
            app.ResultDisplay.Value = txt + mul + func + "(";
    end

    end

    % 7. Parenthesis Buttons
    function ParenthesisButtonPushed(app, event)
    app.ResultDisplay.Value = ...
        string(app.ResultDisplay.Value) + event.Source.Text;
    end

    % 8. Pi Buttons
    function PiButtonPushed(app, ~)
    currentText = string(app.ResultDisplay.Value);

     if currentText == "0"
        app.ResultDisplay.Value = "pi";
     else
        app.ResultDisplay.Value = currentText + "*pi";
     end
    end
    
    % 9. SinGraph Buttons
    function SinGraphButtonPushed(app, ~)
        x = linspace(-2*pi, 2*pi, 1000);
        y = sin(x);

        cla(app.GraphAxes)           % clear old graph
        plot(app.GraphAxes, x, y)    % plot in UIAxes
        grid(app.GraphAxes, 'on')
        title(app.GraphAxes, 'sin(x)')
        xlabel(app.GraphAxes, 'x')
        ylabel(app.GraphAxes, 'sin(x)')
    end

    % 10. CosGraph Buttons
    function CosGraphButtonPushed(app, ~)
        x = linspace(-2*pi, 2*pi, 1000);
        y = cos(x);

        cla(app.GraphAxes)
        plot(app.GraphAxes, x, y)
        grid(app.GraphAxes, 'on')
        title(app.GraphAxes, 'cos(x)')
        xlabel(app.GraphAxes, 'x')
        ylabel(app.GraphAxes, 'cos(x)')
    end

    % 11. TanGraph Buttons
    function TanGraphButtonPushed(app, ~)

     % Define x range (avoid asymptotes at ±pi/2)
     x = linspace(-pi/2 + 0.01, pi/2 - 0.01, 1000);
 
     % Compute tan(x)
     y = tan(x);
 
     % Clear previous graph
     cla(app.GraphAxes)
 
     % Plot tan(x)
     plot(app.GraphAxes, x, y, 'LineWidth', 1.5)
 
     % Formatting
     grid(app.GraphAxes, 'on')
     ylim(app.GraphAxes, [-10 10])   % limit y-axis for visibility
     title(app.GraphAxes, 'tan(x)')
     xlabel(app.GraphAxes, 'x')
     ylabel(app.GraphAxes, 'tan(x)')

    end

end 


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_0, Button_1, Button_2, Button_3, 
        % ...and 7 other components
        function Button_7Pushed(app, event)
            app.NumberButtonPushed(event);
        end

        % Button pushed function: Button_Add, Button_Divide, 
        % ...and 2 other components
        function Button_DividePushed(app, event)
            app.OperationButtonPushed(event);
        end

        % Button pushed function: Button_Equals
        function Button_EqualsPushed(app, event)
            app.EqualsButtonPushed(event);
        end

        % Button pushed function: Button_Clear
        function Button_ClearPushed(app, event)
            app.ClearButtonPushed(event);
        end

        % Button pushed function: Button_Cos, Button_Log, Button_Negate, 
        % ...and 5 other components
        function Button_SinPushed(app, event)
            app.ScienceButtonPushed(event)
        end

        % Button pushed function: Button_OpenParen
        function Button_OpenParenPushed(app, event)
            app.OperationButtonPushed(event);
        end

        % Button pushed function: Button_ClosedParen
        function Button_ClosedParenPushed(app, event)
            app.OperationButtonPushed(event);
        end

        % Button pushed function: Button_Power
        function Button_PowerPushed(app, event)
            app.OperationButtonPushed(event);
        end

        % Button pushed function: Button_BackSpace
        function Button_BackSpacePushed(app, event)
            app.BackspaceButtonPushed(event);
        end

        % Button pushed function: piButton
        function piButtonPushed(app, event)
            app.PiButtonPushed(event);
        end

        % Button pushed function: Button_SinGraph
        function Button_SinGraphPushed(app, event)
            app.SinGraphButtonPushed(event)
        end

        % Button pushed function: Button_CosGraph
        function Button_CosGraphPushed(app, event)
            app.CosGraphButtonPushed(event)
        end

        % Button pushed function: Button_TanGraph
        function Button_TanGraphPushed(app, event)
            app.TanGraphButtonPushed(event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GraphAxes = uiaxes(app.GridLayout);
            title(app.GraphAxes, 'Title')
            xlabel(app.GraphAxes, 'X')
            ylabel(app.GraphAxes, 'Y')
            zlabel(app.GraphAxes, 'Z')
            app.Button_Sin = uibutton(app.GridLayout, 'push');
            app.Button_Sin.ButtonPushedFcn = createCallbackFcn(app, @Button_SinPushed, true);
            app.Button_Sin.Text = 'sin';
            app.Button_Cos = uibutton(app.GridLayout, 'push');
            app.Button_Cos.ButtonPushedFcn = createCallbackFcn(app, @Button_SinPushed, true);
            app.Button_Cos.Text = 'cos';
            app.Button_Tan = uibutton(app.GridLayout, 'push');
            app.Button_Tan.ButtonPushedFcn = createCallbackFcn(app, @Button_SinPushed, true);
            app.Button_Tan.Text = 'tan';
            app.Button_Log = uibutton(app.GridLayout, 'push');
            app.Button_Log.ButtonPushedFcn = createCallbackFcn(app, @Button_SinPushed, true);
            app.Button_Log.Text = 'log';
            app.Button_Clear = uibutton(app.GridLayout, 'push');
            app.Button_Clear.ButtonPushedFcn = createCallbackFcn(app, @Button_ClearPushed, true);
            app.Button_Clear.Text = 'C';
            app.Button_Square = uibutton(app.GridLayout, 'push');
            app.Button_Square.ButtonPushedFcn = createCallbackFcn(app, @Button_SinPushed, true);
            app.Button_Square.Text = 'x^2';
            app.Button_Sqrt = uibutton(app.GridLayout, 'push');
            app.Button_Sqrt.ButtonPushedFcn = createCallbackFcn(app, @Button_SinPushed, true);
            app.Button_Sqrt.Text = 'sqrt';
            app.Button_OpenParen = uibutton(app.GridLayout, 'push');
            app.Button_OpenParen.ButtonPushedFcn = createCallbackFcn(app, @Button_OpenParenPushed, true);
            app.Button_OpenParen.Text = '(';
            app.Button_Divide = uibutton(app.GridLayout, 'push');
            app.Button_Divide.ButtonPushedFcn = createCallbackFcn(app, @Button_DividePushed, true);
            app.Button_Divide.Text = '/';
            app.Button_ClosedParen = uibutton(app.GridLayout, 'push');
            app.Button_ClosedParen.ButtonPushedFcn = createCallbackFcn(app, @Button_ClosedParenPushed, true);
            app.Button_ClosedParen.Text = ')';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_9.Text = '9';
            app.Button_Multiply = uibutton(app.GridLayout, 'push');
            app.Button_Multiply.ButtonPushedFcn = createCallbackFcn(app, @Button_DividePushed, true);
            app.Button_Multiply.Text = '*';
            app.Button_0 = uibutton(app.GridLayout, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_0.Text = '0';
            app.Button_Decimal = uibutton(app.GridLayout, 'push');
            app.Button_Decimal.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_Decimal.Text = '.';
            app.Button_Negate = uibutton(app.GridLayout, 'push');
            app.Button_Negate.ButtonPushedFcn = createCallbackFcn(app, @Button_SinPushed, true);
            app.Button_Negate.Text = '+/-';
            app.Button_Equals = uibutton(app.GridLayout, 'push');
            app.Button_Equals.ButtonPushedFcn = createCallbackFcn(app, @Button_EqualsPushed, true);
            app.Button_Equals.Text = '=';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_5.Text = '5';
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_3.Text = '3';
            app.Button_Add = uibutton(app.GridLayout, 'push');
            app.Button_Add.ButtonPushedFcn = createCallbackFcn(app, @Button_DividePushed, true);
            app.Button_Add.Text = '+';
            app.Subtract = uibutton(app.GridLayout, 'push');
            app.Subtract.ButtonPushedFcn = createCallbackFcn(app, @Button_DividePushed, true);
            app.Subtract.Text = '-';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_6.Text = '6';
            app.xButton_Inverse = uibutton(app.GridLayout, 'push');
            app.xButton_Inverse.ButtonPushedFcn = createCallbackFcn(app, @Button_SinPushed, true);
            app.xButton_Inverse.Text = '1/x';
            app.piButton = uibutton(app.GridLayout, 'push');
            app.piButton.ButtonPushedFcn = createCallbackFcn(app, @piButtonPushed, true);
            app.piButton.Text = 'pi';
            app.Button_Power = uibutton(app.GridLayout, 'push');
            app.Button_Power.ButtonPushedFcn = createCallbackFcn(app, @Button_PowerPushed, true);
            app.Button_Power.Text = '^';
            app.Button_BackSpace = uibutton(app.GridLayout, 'push');
            app.Button_BackSpace.ButtonPushedFcn = createCallbackFcn(app, @Button_BackSpacePushed, true);
            app.Button_BackSpace.Text = 'DEL';
            app.ResultDisplay = uieditfield(app.GridLayout, 'text');
            app.ResultDisplay.Value = '0';
            app.Button_SinGraph = uibutton(app.GridLayout, 'push');
            app.Button_SinGraph.ButtonPushedFcn = createCallbackFcn(app, @Button_SinGraphPushed, true);
            app.Button_SinGraph.Text = 'sin graph';
            app.Button_CosGraph = uibutton(app.GridLayout, 'push');
            app.Button_CosGraph.ButtonPushedFcn = createCallbackFcn(app, @Button_CosGraphPushed, true);
            app.Button_CosGraph.Text = 'cos graph';
            app.Button_TanGraph = uibutton(app.GridLayout, 'push');
            app.Button_TanGraph.ButtonPushedFcn = createCallbackFcn(app, @Button_TanGraphPushed, true);
            app.Button_TanGraph.Text = 'tan graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScientificCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end